classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        HelpMenu                 matlab.ui.container.Menu
        SampleinputformatMenu    matlab.ui.container.Menu
        FeedbackMenu             matlab.ui.container.Menu
        AboutusMenu              matlab.ui.container.Menu
        WhoweareMenu             matlab.ui.container.Menu
        WhatdowedoMenu           matlab.ui.container.Menu
        TeamDontBandwidthUsMenu  matlab.ui.container.Menu
        VivekAsharMenu           matlab.ui.container.Menu
        PreetPanditMenu          matlab.ui.container.Menu
        PriyadarshiniMenu_2      matlab.ui.container.Menu
        Image                    matlab.ui.control.Image
        InitiativeDropDown       matlab.ui.control.DropDown
        InitiativeDropDownLabel  matlab.ui.control.Label
        MonthDropDown            matlab.ui.control.DropDown
        MonthLabel               matlab.ui.control.Label
        EnterthesheetnameofthedaywisecalendersheetEditField  matlab.ui.control.EditField
        EnterthesheetnameofthedaywisecalendersheetEditFieldLabel  matlab.ui.control.Label
        GeneratethefacultyloadsheetButton  matlab.ui.control.Button
        GeneratethefacultycalenderButton  matlab.ui.control.Button
        GeneratethemastercalenderButton  matlab.ui.control.Button
        EnterthefullpathtothedaywisecalenderworkbookEditField  matlab.ui.control.EditField
        EnterthefullpathtothedaywisecalenderworkbookEditFieldLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GeneratethemastercalenderButton
        function GeneratethemastercalenderButtonPushed(app, event)
            Pathh=app.EnterthefullpathtothedaywisecalenderworkbookEditField.Value;
            SheetName=app.EnterthesheetnameofthedaywisecalendersheetEditField.Value;
            InitiativeName = app.InitiativeDropDown.Value;
            MonthName = app.MonthDropDown.Value;
           %% Import data from spreadsheet
% Script for importing data from the following spreadsheet:
%
%    Workbook: C:\Users\vv972\OneDrive\Documents\MATLAB\Excel case study\Excel Automation\Automation_Sample Calender_v0.1.xlsx
%    Worksheet: Simple
%
% Auto-generated by MATLAB on 19-Jul-2021 13:12:3

%% Taking data from GUI
Initiative = InitiativeName;
OutMonth = MonthName;
%% Set up the Import Options and import the data

opts = spreadsheetImportOptions("NumVariables", 11);

% Specify sheet and range
opts.Sheet = SheetName;
opts.DataRange = "A4:K34";

% Specify column names and types
opts.VariableNames = ["Month", "Date", "Day", "CourseCode", "Module", "Leads", "Leads1", "Leads2", "SessionSlot", "SessionTime", "Comments"];
opts.VariableTypes = ["string", "int8", "string", "string", "string", "string", "string", "string", "string", "string", "string"];

% Specify variable properties
opts = setvaropts(opts, "Month", "WhitespaceRule", "preserve");
opts = setvaropts(opts, ["Month", "Day", "CourseCode", "Module", "Leads", "Leads1", "Leads2", "SessionSlot", "SessionTime", "Comments"], "EmptyFieldRule", "auto");

% Import the data
tbl = readtable(Pathh, opts, "UseExcel", false);

%% Assigning data to matlab variables
Month = tbl.Month;
Date = tbl.Date;
Day = tbl.Day;
CourseCode = tbl.CourseCode;
Module = tbl.Module;
Leads = tbl.Leads;
Leads1 = tbl.Leads1;
Leads2 = tbl.Leads2;
SessionSlot = tbl.SessionSlot;
SessionTime = tbl.SessionTime;
Comments = tbl.Comments;

%% Key importing from Master.xlsx Key worksheet
% Auto-generated by MATLAB on 23-Jul-2021 11:34:12

%% Set up the Import Options and import the data
optsKey = spreadsheetImportOptions("NumVariables", 8);

% Specify sheet and range
optsKey.Sheet = "Key";
optsKey.DataRange = "A2:H102";

% Specify column names and types
optsKey.VariableNames = ["FixedInitiativeTitles", "FixedInitiativeCodes", "FixedInitiativeColourCodes", "VarName4", "VarName5", "VarName6", "FixedCourseCodes", "FixedCourseTitles"];
optsKey.VariableTypes = ["string", "double", "string", "string", "string", "string", "string", "string"];

% Specify variable properties
optsKey = setvaropts(optsKey, ["FixedInitiativeTitles", "FixedInitiativeColourCodes", "VarName4", "VarName5", "VarName6", "FixedCourseCodes", "FixedCourseTitles"], "WhitespaceRule", "preserve");
optsKey = setvaropts(optsKey, ["FixedInitiativeTitles", "FixedInitiativeColourCodes", "VarName4", "VarName5", "VarName6", "FixedCourseCodes", "FixedCourseTitles"], "EmptyFieldRule", "auto");

% Import the data
tbKey = readtable("C:\Users\vv972\OneDrive\Documents\MATLAB\Excel case study\Master.xlsx", optsKey, "UseExcel", false);

%% Convert to output type
FixedInitiativeTitles = tbKey.FixedInitiativeTitles;
FixedInitiativeTitles = FixedInitiativeTitles(FixedInitiativeTitles~="");
FixedInitiativeCodes = tbKey.FixedInitiativeCodes;
FixedInitiativeCodes = FixedInitiativeCodes(1:length(FixedInitiativeTitles));
FixedInitiativeColourCodes = tbKey.FixedInitiativeColourCodes;
VarName4 = tbKey.VarName4;
VarName5 = tbKey.VarName5;
VarName6 = tbKey.VarName6;
FixedCourseCodes = tbKey.FixedCourseCodes;
FixedCourseCodes = FixedCourseCodes(FixedCourseCodes~="");
FixedCourseTitles = tbKey.FixedCourseTitles;
FixedCourseTitles = FixedCourseTitles(FixedCourseTitles~="");

%% Fixed courses
FixedCourses = [FixedCourseCodes FixedCourseTitles];

%% Importing the existing OutMonth.xlsx sheetdata from Master.xlsx workbook of fixed size = length(FixedCourseCodes)x 69

[~,UniqueCourseCodeOutMonth,~] = xlsread("C:\Users\vv972\OneDrive\Documents\MATLAB\Excel case study\Master.xlsx", OutMonth, 'A4:A9');
[~,RespectiveCourseTitleOutMonth,~] = xlsread("C:\Users\vv972\OneDrive\Documents\MATLAB\Excel case study\Master.xlsx", OutMonth, 'B4:B9');
[~,RespectiveFacultyOutMonth,~] = xlsread("C:\Users\vv972\OneDrive\Documents\MATLAB\Excel case study\Master.xlsx", OutMonth, 'C4:G9');
[TimeTableOutMonth,~,~] = xlsread("C:\Users\vv972\OneDrive\Documents\MATLAB\Excel case study\Master.xlsx", OutMonth, 'H4:BQ9');
UniqueCourseCodeOutMonthLength = length(UniqueCourseCodeOutMonth);
[~,RespectiveFacultyWidthOutMonth] = size(RespectiveFacultyOutMonth);

%% Fixing the error course codes
for i=1:length(CourseCode)
    if CourseCode(i,1)~=""
        TempFlag=0;
        for j=1:length(FixedCourses)
            if CourseCode(i,1)==FixedCourses(j,1)
                TempFlag=1;
            end
        end
        if TempFlag==0
            TempFlagError = 1;
            for k=1:length(FixedCourses)
                if Module(i)==FixedCourses(k,2)
                    CourseCode(i)=FixedCourses(k,1);
                    TempFlagError = 0;
                end
            end
            if TempFlagError == 1
                CourseCode(i)="";
            end
        end
    end
end

%% Defining different initiatives
FixedInitiatives = [FixedInitiativeTitles FixedInitiativeCodes];

%% Selecting the particular initiative code 
InitiativeCode = 11;
for i=1:length(FixedInitiatives)
    if ismember(Initiative,FixedInitiatives(i,1))
        InitiativeCode = FixedInitiatives(i,2);
    end
end

InitiativeCode = str2double(InitiativeCode);
%% UniqueCourseCode containing unique data for CourseCode
UniqueCourseCode = unique(CourseCode);
UniqueCourseCode = UniqueCourseCode(UniqueCourseCode~="");

%% Data containing the data-wise module names and respective faculties
Data = [Module Leads Leads1 Leads2];

%% Declaring variable to hold respective CourseTitle for UniqueCourseCode
RespectiveCourseTitle = strings(length(UniqueCourseCode),1);

%% Declaring matrix to hold repeatitive list of faculties for respective UniqueCourseCode
Faculty=strings(length(UniqueCourseCode),length(CourseCode)*3);

%% Declaring matrix to hold respective list of faculties for respective UniqueCourseCode
RespectiveFaculty=strings(length(UniqueCourseCode),5);

%% Initialising a TimeTable of zeros for UniqueCourseCode for a month of 31 days
TimeTable=zeros(length(UniqueCourseCode),62);

%% Logically assigning a CourseTitle, Faculty for every UniqueCourseCode
for i=1:length(UniqueCourseCode)
    for j=1:length(CourseCode)        
        if ismember(UniqueCourseCode(i,1),CourseCode(j,1))
            RespectiveCourseTitle(i,1)=Data(j,1);
            Faculty(i,((j-1)*3)+1:((j-1)*3)+3)=Data(j,2:4);
            if SessionSlot(j)=='M'
                TimeTable(i,(2*Date(j)-1))=InitiativeCode;
            elseif SessionSlot(j)=='A'
                TimeTable(i,2*Date(j))=InitiativeCode;
            elseif SessionSlot(j)=='F'
                TimeTable(i,(2*Date(j)-1))=InitiativeCode;
                TimeTable(i,2*Date(j))=InitiativeCode;
            end
        end    
    end    
end


for i=1:length(UniqueCourseCode)
    UniqueFaculty = unique(Faculty(i,:));
    UniqueFaculty = UniqueFaculty(UniqueFaculty~="");
    RespectiveFaculty(i,1:length(UniqueFaculty))=UniqueFaculty(:);
end


%% Writing UniqueCourseCode, RespectiveCourseTitle, RespectiveFaculty and TimeTable onto the Output.xlsx

if isempty(UniqueCourseCodeOutMonth)
    xlswrite('Master.xlsx',UniqueCourseCode,OutMonth,'A4');
    xlswrite('Master.xlsx',RespectiveCourseTitle,OutMonth,'B4');
    xlswrite('Master.xlsx',RespectiveFaculty,OutMonth,'C4');
    xlswrite('Master.xlsx',TimeTable,OutMonth,'H4');
else
    %Initialising the final outputs of UniqueCourseCodesFinal, RespectiveCourseTitleFinal, RespectiveFacultyFinal
    TempCounterFinal = 0;
    for i=1:length(UniqueCourseCode)
        for j=1:length(UniqueCourseCodeOutMonth)
            if ismember(UniqueCourseCode(i,1),UniqueCourseCode(j,1))
                TempCounterFinal= TempCounterFinal + 1;
                break;
            end
        end
    end
    FinalLength = length(UniqueCourseCodeOutMonth) + length(UniqueCourseCode) - TempCounterFinal;    
    
    FinalUniqueCourseCode = strings(FinalLength,1);
    FinalUniqueCourseCode(1:length(UniqueCourseCodeOutMonth),1)= UniqueCourseCodeOutMonth(:,1);
    
    FinalRespectiveCourseTitle = strings(FinalLength,1);
    FinalRespectiveCourseTitle(1:length(UniqueCourseCodeOutMonth),1)= RespectiveCourseTitleOutMonth(:,1);
    
    FinalRespectiveFaculty = strings(FinalLength,5);
    FinalRespectiveFaculty(1:length(UniqueCourseCodeOutMonth),1:RespectiveFacultyWidthOutMonth) = RespectiveFacultyOutMonth;

    FinalTimeTable = zeros(FinalLength,62);
    FinalTimeTable(1:length(UniqueCourseCodeOutMonth),:) = TimeTableOutMonth;
    
    TempCounterCourse = 1;
    TempCounterFaculty = 1;
    for i=1:length(UniqueCourseCode)
        TempFlagCourse=0;
        for j=1:length(FinalUniqueCourseCode)
            if ismember(UniqueCourseCode(i,1),FinalUniqueCourseCode(j,1))
                TempFlagCourse = 1;
                TempRow = j;
            end
        end
        if TempFlagCourse == 1
            RespectiveFacultyLength = unique(RespectiveFaculty(i,:));
            RespectiveFacultyLength = length(RespectiveFacultyLength(RespectiveFacultyLength~=""));
            FinalRespectiveFacultyLength = unique(FinalRespectiveFaculty(TempRow,:));
            FinalRespectiveFacultyLength = length(FinalRespectiveFacultyLength(FinalRespectiveFacultyLength~=""));        
        
            for x=1:RespectiveFacultyLength
                TempFlagFaculty = 0;
                for y=1:FinalRespectiveFacultyLength
                    if ismember(RespectiveFaculty(i,x),FinalRespectiveFaculty(TempRow,y))
                        TempFlagFaculty = 1;
                    end
                end
                if TempFlagFaculty == 0
                    FinalRespectiveFaculty(TempRow,FinalRespectiveFacultyLength+TempCounterFaculty) = RespectiveFaculty(i,x);
                    TempCounterFaculty = TempCounterFaculty +1;
                end
                if (TempCounterFaculty + FinalRespectiveFacultyLength) > 5
                    TempCounterFaculty = 1;
                    break;
                end
            end
            for a=1:62
                if TimeTable(i,a) == InitiativeCode
                    FinalTimeTable(TempRow,a) = TimeTable(i,a);
                end
            end
        elseif TempFlagCourse == 0
            TempRowFinal = length(UniqueCourseCodeOutMonth)+ TempCounterCourse;
            FinalUniqueCourseCode(TempRowFinal,1) = UniqueCourseCode(i,1);
            FinalRespectiveCourseTitle(TempRowFinal,1) = RespectiveCourseTitle(i,1);
            FinalRespectiveFaculty(TempRowFinal,:) = RespectiveFaculty(i,:);
            FinalTimeTable(TempRowFinal,:)=TimeTable(i,:);
            TempCounterCourse = TempCounterCourse + 1;
            if TempCounterCourse > length(FixedCourseCodes)
                break;
            end
        end
    end
    xlswrite('Master.xlsx',FinalUniqueCourseCode,OutMonth,'A4');
    xlswrite('Master.xlsx',FinalRespectiveCourseTitle,OutMonth,'B4');
    xlswrite('Master.xlsx',FinalRespectiveFaculty,OutMonth,'C4');
    xlswrite('Master.xlsx',FinalTimeTable,OutMonth,'H4');
end


%% Rows not analysed

RowsNotAnalysed = zeros(length(CourseCode(CourseCode=="")));
TempRowsCounter = 1;
 for i=1:length(CourseCode)
     if CourseCode(i,1)==""
         RowsNotAnalysed(TempRowsCounter)=Date(i);
         TempRowsCounter = TempRowsCounter + 1;
     end
 end
 RowsNotAnalysed=RowsNotAnalysed(RowsNotAnalysed ~=0);
 
fig = uifigure;
uialert(fig,sprintf('Date %g was not analysed\n',RowsNotAnalysed),'Warning');
message = sprintf('Master Calendar Updated');
uialert(fig,message,'Update','Icon','success'); 
%% Clear temporary variables
clear opts tbl
            
        end

        % Button pushed function: GeneratethefacultycalenderButton
        function GeneratethefacultycalenderButtonPushed(app, event)
                        pathhh=app.EnterthefullpathtothedaywisecalenderworkbookEditField.Value;
                        ShetttName = app.EnterthesheetnameofthedaywisecalendersheetEditField.Value;
                        %% Import data from spreadsheet
% Script for importing data from the following spreadsheet:
opts = spreadsheetImportOptions("NumVariables", 11);

% Specify sheet and range 
opts.Sheet = ShetttName;
opts.DataRange = "A4:K100";

% Specify column names and types
opts.VariableNames = ["VarName1", "Date", "Day", "CourseCode", "Module", "Leads", "Leads1", "Leads2", "SessionSlot", "SessionTime", "Comments"];
opts.VariableTypes = ["string", "double", "categorical", "categorical", "categorical", "categorical", "categorical", "categorical", "categorical", "categorical", "categorical"];

% Specify variable properties
opts = setvaropts(opts, "VarName1", "WhitespaceRule", "preserve");
opts = setvaropts(opts, ["VarName1", "Day", "CourseCode", "Module", "Leads", "Leads1", "Leads2", "SessionSlot", "SessionTime", "Comments"], "EmptyFieldRule", "auto");

% Import the data
Automation_SampleCalender_v0_1 = readtable(pathhh, opts, "UseExcel", false);
%% Convert to output type

VarName1 = Automation_SampleCalender_v0_1.VarName1;
Date = Automation_SampleCalender_v0_1.Date;
Day = Automation_SampleCalender_v0_1.Day;
CourseCode = Automation_SampleCalender_v0_1.CourseCode;
Module = Automation_SampleCalender_v0_1.Module;
Leads = Automation_SampleCalender_v0_1.Leads;
Leads1 = Automation_SampleCalender_v0_1.Leads1;
Leads2 = Automation_SampleCalender_v0_1.Leads2;
SessionSlot = Automation_SampleCalender_v0_1.SessionSlot;
SessionTime = Automation_SampleCalender_v0_1.SessionTime;
Comments = Automation_SampleCalender_v0_1.Comments;
%% Clear temporary variables

clear Automation_SampleCalender_v0_1 opts
TogetherArray = cat(1,Leads,Leads1,Leads2);
FacultyList = unique(TogetherArray);
FacultyList = rmmissing(FacultyList);
FacultyList = upper(FacultyList);
Month = app.MonthDropDown.Value;
Month = upper(Month);
Initiative = app.InitiativeDropDown.Value;
FacultyMSlots = zeros(length(FacultyList),31);
FacultyASlots = zeros(length(FacultyList),31);
for i=1:length(Leads)
    for j = 1:length(FacultyList)
        if(Leads(i)==FacultyList(j) || Leads1(i)==FacultyList(j) || Leads2(i)==FacultyList(j) )
            if(SessionSlot(i)=='M')
                FacultyMSlots(j,Date(i)) = FacultyMSlots(j,Date(i)) + 1;
            elseif(SessionSlot(i)=='A')
                FacultyASlots(j,Date(i)) = FacultyASlots(j,Date(i)) + 1;
            elseif(SessionSlot(i)=='F')
                FacultyMSlots(j,Date(i)) = FacultyMSlots(j,Date(i)) + 1;
                FacultyASlots(j,Date(i)) = FacultyASlots(j,Date(i)) + 1;
            end
        end
    end
end
for i=1:length(FacultyList)
    for j=1:31
        if(Initiative=="GENESIS PRO")&(FacultyMSlots(i,j)==1)
            FacultyMSlots(i,j) = 2;
        elseif(Initiative=="BUILD / STEP UP")&(FacultyMSlots(i,j)==1)
            FacultyMSlots(i,j) = 3;
        elseif(Initiative=="OPEN TRAININGS")&(FacultyMSlots(i,j)==1)
            FacultyMSlots(i,j) = 4;
        elseif(Initiative=="STEPin")&(FacultyMSlots(i,j)==1)
            FacultyMSlots(i,j) = 5;
        elseif(Initiative=="TEECH PRAAAVINYA")&(FacultyMSlots(i,j)==1)
            FacultyMSlots(i,j) = 6;
        elseif(Initiative=="OTHERS")&(FacultyMSlots(i,j)==1)
            FacultyMSlots(i,j) = 7;
        end
        if(Initiative=="GENESIS PRO")&(FacultyASlots(i,j)==1)
            FacultyASlots(i,j) = 2;
        elseif(Initiative=="BUILD / STEP UP")&(FacultyASlots(i,j)==1)
            FacultyASlots(i,j) = 3;
        elseif(Initiative=="OPEN TRAININGS")&(FacultyASlots(i,j)==1)
            FacultyASlots(i,j) = 4;
        elseif(Initiative=="STEPin")&(FacultyASlots(i,j)==1)
            FacultyASlots(i,j) = 5;
        elseif(Initiative=="TEECH PRAAAVINYA")&(FacultyASlots(i,j)==1)
            FacultyASlots(i,j) = 6;
        elseif(Initiative=="OTHERS")&(FacultyASlots(i,j)==1)
            FacultyASlots(i,j) = 7;
        end
    end
end

Months =            ["JANUARY","FEBRUARY","MARCH","APRIL","MAY","JUNE","JULY","AUGUST","SEPTEMBER","OCTOBER","NOVEMBER","DECEMBER"];
Range = [5,24,31,50,57,76,83,102,109,128,135,154,161,180,187,206,213,232,239,258,265,284,291,310]; 
FacultyRange = ["B5:B24","B31:50","B57:B76","B83:B102","B109:B128","B135:B154","B161:B180","B187:B206","B213:B232","B238:B258","B265:B284","B291:B310"];
MAlpha = ['A','C','E','G','I','K','M','O','Q','S','U','W','Y'];
AAlpha = ['B','D','F','H','J','L','N','P','R','T','V','X','Z'];
for i=1:12
    if(Month == Months(i))
        index=i;
    end
end
[~,FacultyDown] = xlsread('FacultyCalendar_Output.xlsx',1,FacultyRange(index));
FacLen = length(FacultyDown);
ExMSlots = zeros(FacLen,31);
ExASlots = zeros(FacLen,31);
for i=1:FacLen
    for j=1:12
        ExMSlots(i,j) = table2array(readtable('FacultyCalendar_Output.xlsx','Range',[MAlpha(j+1),num2str(Range(index*2-1)+i-1),':',MAlpha(j+1),num2str(Range(index*2-1)+i-1)]));
        ExASlots(i,j) = table2array(readtable('FacultyCalendar_Output.xlsx','Range',[AAlpha(j+1),num2str(Range(index*2-1)+i-1),':',AAlpha(j+1),num2str(Range(index*2-1)+i-1)]));
    end
    for j=13:25
        ExMSlots(i,j) = table2array(readtable('FacultyCalendar_Output.xlsx','Range',['A',MAlpha(j-12),num2str(Range(index*2-1)+i-1),':','A',MAlpha(j-12),num2str(Range(index*2-1)+i-1)]));
        ExASlots(i,j) = table2array(readtable('FacultyCalendar_Output.xlsx','Range',['A',AAlpha(j-12),num2str(Range(index*2-1)+i-1),':','A',AAlpha(j-12),num2str(Range(index*2-1)+i-1)]));
    end
    for j=26:31
        ExMSlots(i,j) = table2array(readtable('FacultyCalendar_Output.xlsx','Range',['B',MAlpha(j-25),num2str(Range(index*2-1)+i-1),':','B',MAlpha(j-25),num2str(Range(index*2-1)+i-1)]));
        ExASlots(i,j) = table2array(readtable('FacultyCalendar_Output.xlsx','Range',['B',AAlpha(j-25),num2str(Range(index*2-1)+i-1),':','B',AAlpha(j-25),num2str(Range(index*2-1)+i-1)]));
    end
end
ExMSlots(isnan(ExMSlots)) = 0;
ExASlots(isnan(ExASlots)) = 0;
UpFaculty = strings(FacLen+length(FacultyList),1);
UpMSlots = zeros(FacLen+length(FacultyList),31);
UpASlots = zeros(FacLen+length(FacultyList),31);
kc=1;
CheckList = zeros(length(FacultyList),1);
CheckList1 = zeros(FacLen,1);
for i=1:FacLen
    for j=1:length(FacultyList)
        if(FacultyDown(i)==FacultyList(j))
            CheckList(j)=1;
            CheckList1(i)=1;
            for k=1:31
                if(ExMSlots(i,k) ~= FacultyMSlots(j,k))
                    if(ExMSlots(i,k)==0)
                        ExMSlots(i,k) = FacultyMSlots(j,k);
                    else
                        ExMSlots(i,k) = (FacultyMSlots(j,k)*10) + (ExMSlots(i,k));
                    end
                end
                if(ExASlots(i,k) ~= FacultyASlots(j,k))
                    if(ExASlots(i,k)==0)
                        ExASlots(i,k) = FacultyASlots(j,k);
                    else
                        ExASlots(i,k) = (FacultyASlots(j,k)*10) + (ExASlots(i,k));
                    end
                end
            end
            UpFaculty(kc) = FacultyDown(i);
            UpMSlots(kc,:) = ExMSlots(i,:);
            UpASlots(kc,:) = ExASlots(i,:);
            kc = kc+1;
            i = i+1;
            break;
        end
    end
end
for i=1:FacLen
    if(CheckList1(i)==0)
        UpFaculty(kc) = FacultyDown(i);
        UpMSlots(kc,:) = ExMSlots(i,:);
        UpASlots(kc,:) = ExASlots(i,:);
        kc = kc+1;
    end
end
for i=1:length(FacultyList)
    if(CheckList(i)==0)
        UpFaculty(kc) = FacultyList(i);
        UpMSlots(kc,:) = FacultyMSlots(i,:);
        UpASlots(kc,:) = FacultyASlots(i,:);
        kc = kc+1;
    end
end
for i=1:kc-1
    writematrix(UpFaculty(i),'FacultyCalendar_Output.xlsx','Sheet',1,'Range',['B',num2str(Range(index*2-1)+i-1)])
    for j=1:12
        writematrix(UpMSlots(i,j),'FacultyCalendar_Output.xlsx','Sheet',1,'Range',[MAlpha(j+1),num2str(Range(index*2-1)+i-1)]);
        writematrix(UpASlots(i,j),'FacultyCalendar_Output.xlsx','Sheet',1,'Range',[AAlpha(j+1),num2str(Range(index*2-1)+i-1)]);
    end
    for j=13:25
        writematrix(UpMSlots(i,j),'FacultyCalendar_Output.xlsx','Sheet',1,'Range',['A',MAlpha(j-12),num2str(Range(index*2-1)+i-1)]);
        writematrix(UpASlots(i,j),'FacultyCalendar_Output.xlsx','Sheet',1,'Range',['A',AAlpha(j-12),num2str(Range(index*2-1)+i-1)]);
    end
    for j=26:31
        writematrix(UpMSlots(i,j),'FacultyCalendar_Output.xlsx','Sheet',1,'Range',['B',MAlpha(j-25),num2str(Range(index*2-1)+i-1)]);
        writematrix(UpMSlots(i,j),'FacultyCalendar_Output.xlsx','Sheet',1,'Range',['B',AAlpha(j-25),num2str(Range(index*2-1)+i-1)]);
    end
end
fig = uifigure;
message = sprintf('Faculty Calendar Updated');
uialert(fig,message,'Update','Icon','success');
        end

        % Button pushed function: GeneratethefacultyloadsheetButton
        function GeneratethefacultyloadsheetButtonPushed(app, event)
            pathhhh = app.EnterthefullpathtothedaywisecalenderworkbookEditField.Value;
            SheettttName = app.EnterthesheetnameofthedaywisecalendersheetEditField.Value;
%% Import data from spreadsheet
% Script for Populating Integrated faculty Load Sheet:
%% Import data from spreadsheet  
% Script for importing data from the following spreadsheet:
%%
% 
%    Workbook: /MATLAB Drive/ExcelAutomation/Automation_Sample Calender_v0.1.xlsx
%    Worksheet: Simple
%
%% 
% Auto-generated by MATLAB on 20-Jul-2021 09:41:36
%% Set up the Import Options and import the data

opts = spreadsheetImportOptions("NumVariables", 11);

% Specify sheet and range
opts.Sheet = SheettttName;
opts.DataRange = "A4:K100";

% Specify column names and types
opts.VariableNames = ["VarName1", "Date", "Day", "CourseCode", "Module", "Leads", "Leads1", "Leads2", "SessionSlot", "SessionTime", "Comments"];
opts.VariableTypes = ["string", "double", "categorical", "categorical", "categorical", "categorical", "categorical", "categorical", "categorical", "categorical", "categorical"];

% Specify variable properties
opts = setvaropts(opts, "VarName1", "WhitespaceRule", "preserve");
opts = setvaropts(opts, ["VarName1", "Day", "CourseCode", "Module", "Leads", "Leads1", "Leads2", "SessionSlot", "SessionTime", "Comments"], "EmptyFieldRule", "auto");

% Import the data
Automation_SampleCalender_v0_1 = readtable(pathhhh, opts, "UseExcel", false);
%% Convert to output type

VarName1 = Automation_SampleCalender_v0_1.VarName1;
Date = Automation_SampleCalender_v0_1.Date;
Day = Automation_SampleCalender_v0_1.Day;
CourseCode = Automation_SampleCalender_v0_1.CourseCode;
Module = Automation_SampleCalender_v0_1.Module;
Leads = Automation_SampleCalender_v0_1.Leads;
Leads1 = Automation_SampleCalender_v0_1.Leads1;
Leads2 = Automation_SampleCalender_v0_1.Leads2;
SessionSlot = Automation_SampleCalender_v0_1.SessionSlot;
SessionTime = Automation_SampleCalender_v0_1.SessionTime;
Comments = Automation_SampleCalender_v0_1.Comments;
%% Clear temporary variables

clear Automation_SampleCalender_v0_1 opts
TogetherArray = cat(1,Leads,Leads1,Leads2);
FacultyList = unique(TogetherArray);
FacultyList = rmmissing(FacultyList);
FacultyList = upper(FacultyList);
MSlots = zeros(length(FacultyList),1);
ASlots = zeros(length(FacultyList),1);
Month = app.MonthDropDown.Value;
Month = upper(Month);
Initiative = app.InitiativeDropDown.Value;
for i=1:length(Leads)
    for j=1:length(FacultyList)
        if(SessionSlot(i)=='M')
            if(Leads(i)==FacultyList(j))
                MSlots(j) = MSlots(j)+1;
            end
            if(Leads1(i)==FacultyList(j))
                MSlots(j) = MSlots(j)+1;
            end
            if(Leads2(i)==FacultyList(j))
                MSlots(j) = MSlots(j)+1;
            end
        elseif(SessionSlot(i)=='A')
            if(Leads(i)==FacultyList(j))
                ASlots(j) = ASlots(j)+1;
            end
            if(Leads1(i)==FacultyList(j))
                ASlots(j) = ASlots(j)+1;
            end
            if(Leads2(i)==FacultyList(j))
                ASlots(j) = ASlots(j)+1;
            end
        elseif(SessionSlot(i)=='F')
            if(Leads(i)==FacultyList(j))
                ASlots(j) = ASlots(j)+1;
                MSlots(j) = MSlots(j)+1;
            end
            if(Leads1(i)==FacultyList(j))
                ASlots(j) = ASlots(j)+1;
                MSlots(j) = MSlots(j)+1;
            end
            if(Leads2(i)==FacultyList(j))
                ASlots(j) = ASlots(j)+1;
                MSlots(j) = MSlots(j)+1;
            end
        end
    end
end
Months =            ["JANUARY","FEBRUARY","MARCH","APRIL","MAY","JUNE","JULY","AUGUST","SEPTEMBER","OCTOBER","NOVEMBER","DECEMBER"];
FacultyRange =      ["B4:B23","B30:B49","B56:B75","B82:B101","B108:B127","B134:B153","B160:B179","B186:B205","B212:B231","B238:B257","B264:B283","B290:B309"];
NumRange =          [4,23,30,49,56,75,82,101,108,127,134,153,160,179,186,205,212,231,238,257,264,283,290,309];
GenesisProgram =    ["C4:D23","C30:D49","C56:D75","C82:D101","C108:D127","C134:D153","C160:D179","C186:D205","C212:D231","C238:D257","C264:D283","C290:D309"];
GenesisProProgram = ["E4:F23","E30:F49","E56:F75","E82:F101","E108:F127","E134:F153","E160:F179","E186:F205","E212:F231","E238:F257","E264:F283","E290:F309"];
BuildProgram =      ["G4:H23","G30:H49","G56:H75","G82:H101","G108:H127","G134:H153","G160:H179","G186:H205","G212:H231","G238:H257","G264:H283","G290:H309"];
OpenProgram =       ["I4:J23","I30:J49","I56:J75","I82:J101","I108:J127","I134:J153","I160:J179","I186:J205","I212:J231","I238:J257","I264:J283","I290:J309"];
StepProgram =       ["K4:L23","K30:L49","K56:L75","K82:L101","K108:L127","K134:L153","K160:L179","K186:L205","K212:L231","K238:L257","K264:L283","K290:L309"];
TeechProgram =      ["M4:N23","M30:N49","M56:N75","M82:N101","M108:N127","M134:N153","M160:N179","M186:N205","M212:N231","M238:N257","M264:N283","M290:N309"];
Others =            ["O4:P23","O30:P49","O56:P75","O82:P101","O108:P127","O134:P153","O160:P179","O186:P205","O212:P231","O238:P257","O264:P283","O290:P309"];
PlannedRange =      ["Q4:R23","Q30:R49","Q56:R75","Q82:R101","Q108:R127","Q134:R153","Q160:R179","Q186:R205","Q212:R231","Q238:R257","Q264:R283","Q290:R309"];
AvailableRange =    ["S4:T23","S30:T49","S56:T75","S82:T101","S108:T127","S134:T153","S160:T179","S186:T205","S212:T231","S238:T257","S264:T283","S290:T309"];
SlotRange =         ["C4:P23","C30:P49","C56:P75","C82:P101","C108:P127","C134:P153","C160:P179","C186:P205","C212:P231","C238:P257","C264:P283","C290:P309"];
WholeRange =        ["B4:P23","B30:P49","B56:P75","B82:P101","B108:P127","B134:P153","B160:P179","B186:P205","B212:P231","B238:P257","B264:P283","B290:P309"];
Slots = cat(2,MSlots,ASlots);
for i=1:12
    if(Month == Months(i))
        index=i;
    end
end
[~,FacultyDow] = xlsread('FacultyLoadSheet_Output.xlsx',1,FacultyRange(index));
lenFac = length(FacultyDow);
CheckList = zeros(length(FacultyList),1);
for i=1:length(FacultyDow)
    for j=1:length(FacultyList)        
        if(FacultyList(j)==FacultyDow(i))
            CheckList(j) = 1;
            if(Initiative == "GENESIS")
                writematrix(MSlots(j),'FacultyLoadSheet_Output.xlsx','Sheet',1,'Range',['C',num2str(NumRange(index*2-1)+i-1)]);
                writematrix(ASlots(j),'FacultyLoadSheet_Output.xlsx','Sheet',1,'Range',['D',num2str(NumRange(index*2-1)+i-1)]);
            elseif(Initiative == "GENESIS PRO")
                writematrix(MSlots(j),'FacultyLoadSheet_Output.xlsx','Sheet',1,'Range',['E',num2str(NumRange(index*2-1)+i-1)]);
                writematrix(ASlots(j),'FacultyLoadSheet_Output.xlsx','Sheet',1,'Range',['F',num2str(NumRange(index*2-1)+i-1)]);
            elseif(Initiative == "BUILD / STEP UP")
                writematrix(MSlots(j),'FacultyLoadSheet_Output.xlsx','Sheet',1,'Range',['G',num2str(NumRange(index*2-1)+i-1)]);
                writematrix(ASlots(j),'FacultyLoadSheet_Output.xlsx','Sheet',1,'Range',['H',num2str(NumRange(index*2-1)+i-1)]);
            elseif(Initiative == "OPEN TRAININGS")
                writematrix(MSlots(j),'FacultyLoadSheet_Output.xlsx','Sheet',1,'Range',['I',num2str(NumRange(index*2-1)+i-1)]);
                writematrix(ASlots(j),'FacultyLoadSheet_Output.xlsx','Sheet',1,'Range',['J',num2str(NumRange(index*2-1)+i-1)]);
            elseif(Initiative == "STEPin")
                writematrix(MSlots(j),'FacultyLoadSheet_Output.xlsx','Sheet',1,'Range',['K',num2str(NumRange(index*2-1)+i-1)]);
                writematrix(ASlots(j),'FacultyLoadSheet_Output.xlsx','Sheet',1,'Range',['L',num2str(NumRange(index*2-1)+i-1)]);
            elseif(Initiative == "TEECH PRAAAVINYA")
                writematrix(MSlots(j),'FacultyLoadSheet_Output.xlsx','Sheet',1,'Range',['M',num2str(NumRange(index*2-1)+i-1)]);
                writematrix(ASlots(j),'FacultyLoadSheet_Output.xlsx','Sheet',1,'Range',['N',num2str(NumRange(index*2-1)+i-1)]);
            elseif(Initiative == "OTHERS")
                writematrix(MSlots(j),'FacultyLoadSheet_Output.xlsx','Sheet',1,'Range',['O',num2str(NumRange(index*2-1)+i-1)]);
                writematrix(ASlots(j),'FacultyLoadSheet_Output.xlsx','Sheet',1,'Range',['P',num2str(NumRange(index*2-1)+i-1)]);
            end
        end
    end
end
if(isempty(FacultyDow))
  writematrix(FacultyList,'FacultyLoadSheet_Output.xlsx','Sheet',1,'Range',FacultyRange(index));
  if(Initiative == "GENESIS")
      writematrix(Slots,'FacultyLoadSheet_Output.xlsx','Sheet',1,'Range',GenesisProgram(index));
  elseif(Initiative == "GENESIS PRO")
      writematrix(Slots,'FacultyLoadSheet_Output.xlsx','Sheet',1,'Range',GenesisProProgram(index));
  elseif(Initiative == "BUILD / STEP UP")
      writematrix(Slots,'FacultyLoadSheet_Output.xlsx','Sheet',1,'Range',BuildProgram(index));
  elseif(Initiative == "OPEN TRAINING")
      writematrix(Slots,'FacultyLoadSheet_Output.xlsx','Sheet',1,'Range',OpenProgram(index));
  elseif(Initiative == "STEPin")
      writematrix(Slots,'FacultyLoadSheet_Output.xlsx','Sheet',1,'Range',StepProgram(index));
  elseif(Initiative == "TEECH PRAAAVINYA")
      writematrix(Slots,'FacultyLoadSheet_Output.xlsx','Sheet',1,'Range',TeechProgram(index));
  elseif(Initiative == "OTHERS")
      writematrix(Slots,'FacultyLoadSheet_Output.xlsx','Sheet',1,'Range',Others(index));
  end  
end
k = -1;
if(~isempty(FacultyDow))
    for j=1:length(FacultyList)
        if(CheckList(j)==0)
            k = k + 1;
            writematrix(FacultyList(j),'FacultyLoadSheet_Output.xlsx','Sheet',1,'Range',['B',num2str(NumRange(index*2-1)+lenFac+k)]);
            if(Initiative == "GENESIS")
                writematrix(MSlots(j),'FacultyLoadSheet_Output.xlsx','Sheet',1,'Range',['C',num2str(NumRange(index*2-1)+lenFac+k)]);
                writematrix(ASlots(j),'FacultyLoadSheet_Output.xlsx','Sheet',1,'Range',['D',num2str(NumRange(index*2-1)+lenFac+k)]);
            elseif(Initiative == "GENESIS PRO")
                writematrix(MSlots(j),'FacultyLoadSheet_Output.xlsx','Sheet',1,'Range',['E',num2str(NumRange(index*2-1)+lenFac+k)]);
                writematrix(ASlots(j),'FacultyLoadSheet_Output.xlsx','Sheet',1,'Range',['F',num2str(NumRange(index*2-1)+lenFac+k)]);
            elseif(Initiative == "BUILD / STEP UP")
                writematrix(MSlots(j),'FacultyLoadSheet_Output.xlsx','Sheet',1,'Range',['G',num2str(NumRange(index*2-1)+lenFac+k)]);
                writematrix(ASlots(j),'FacultyLoadSheet_Output.xlsx','Sheet',1,'Range',['H',num2str(NumRange(index*2-1)+lenFac+k)]);
            elseif(Initiative == "OPEN TRAININGS")
                writematrix(MSlots(j),'FacultyLoadSheet_Output.xlsx','Sheet',1,'Range',['I',num2str(NumRange(index*2-1)+lenFac+k)]);
                writematrix(ASlots(j),'FacultyLoadSheet_Output.xlsx','Sheet',1,'Range',['J',num2str(NumRange(index*2-1)+lenFac+k)]);
            elseif(Initiative == "STEPin")
                writematrix(MSlots(j),'FacultyLoadSheet_Output.xlsx','Sheet',1,'Range',['K',num2str(NumRange(index*2-1)+lenFac+k)]);
                writematrix(ASlots(j),'FacultyLoadSheet_Output.xlsx','Sheet',1,'Range',['L',num2str(NumRange(index*2-1)+lenFac+k)]);
            elseif(Initiative == "TEECH PRAAAVINYA")
                writematrix(MSlots(j),'FacultyLoadSheet_Output.xlsx','Sheet',1,'Range',['M',num2str(NumRange(index*2-1)+lenFac+k)]);
                writematrix(ASlots(j),'FacultyLoadSheet_Output.xlsx','Sheet',1,'Range',['N',num2str(NumRange(index*2-1)+lenFac+k)]);
            elseif(Initiative == "OTHERS")
                writematrix(MSlots(j),'FacultyLoadSheet_Output.xlsx','Sheet',1,'Range',['O',num2str(NumRange(index*2-1)+lenFac+k)]);
                writematrix(ASlots(j),'FacultyLoadSheet_Output.xlsx','Sheet',1,'Range',['P',num2str(NumRange(index*2-1)+lenFac+k)]);
            end
        end
    end
end
[~,UpFacultyList] = xlsread('FacultyLoadSheet_Output.xlsx',1,FacultyRange(index));
PlannedSlots = zeros(length(UpFacultyList),2);
AvailableSlots = zeros(length(UpFacultyList),2);
for i = 1:length(UpFacultyList)
    for j = 1:2
        PlannedSlots(i,j) = 18;
    end
end
Slots = xlsread('FacultyLoadSheet_Output.xlsx',1,SlotRange(index));
Slots(isnan(Slots))=0;
for i=1:length(UpFacultyList)
    writematrix(PlannedSlots(i,1),'FacultyLoadSheet_Output.xlsx','Sheet',1,'Range',['Q',num2str(NumRange(index*2-1)+i-1)]);
    writematrix(PlannedSlots(i,2),'FacultyLoadSheet_Output.xlsx','Sheet',1,'Range',['R',num2str(NumRange(index*2-1)+i-1)]);
    AvailableSlots(i,1) = PlannedSlots(i,1) - sum(Slots(i,1:2:end));
    AvailableSlots(i,2) = PlannedSlots(i,2) - sum(Slots(i,2:2:end));
    writematrix(AvailableSlots(i,1),'FacultyLoadSheet_Output.xlsx','Sheet',1,'Range',['S',num2str(NumRange(index*2-1)+i-1)]);
    writematrix(AvailableSlots(i,2),'FacultyLoadSheet_Output.xlsx','Sheet',1,'Range',['T',num2str(NumRange(index*2-1)+i-1)]);
end
fig = uifigure;
message = sprintf('Faculty Load Sheet Updated');
uialert(fig,message,'Update','Icon','success');
        end

        % Menu selected function: VivekAsharMenu
        function VivekAsharMenuSelected(app, event)
            web('https://www.linkedin.com/in/vivek-ashar-82940b209/');
        end

        % Menu selected function: FeedbackMenu
        function FeedbackMenuSelected(app, event)
            web('https://forms.gle/7m8d6SEtF6cuB6My6');
        end

        % Menu selected function: SampleinputformatMenu
        function SampleinputformatMenuSelected(app, event)
            web('https://drive.google.com/file/d/1shFWE0lcZuYtkP2pFwBAM0b9ZJ-Uun24/view?usp=sharing');
        end

        % Menu selected function: PreetPanditMenu
        function PreetPanditMenuSelected(app, event)
            web('https://www.linkedin.com/in/preet-pandit-100972178');
        end

        % Menu selected function: WhoweareMenu
        function WhoweareMenuSelected(app, event)
            web("https://www.publishthis.email/team-don't-bandwidth-us-SkxmaiLAd");
        end

        % Menu selected function: PriyadarshiniMenu_2
        function PriyadarshiniMenu_2Selected(app, event)
            web("https://www.linkedin.com/in/priyadharshni-n-205b351a4");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.SampleinputformatMenu = uimenu(app.HelpMenu);
            app.SampleinputformatMenu.MenuSelectedFcn = createCallbackFcn(app, @SampleinputformatMenuSelected, true);
            app.SampleinputformatMenu.Text = 'Sample input format';
            app.FeedbackMenu = uimenu(app.HelpMenu);
            app.FeedbackMenu.MenuSelectedFcn = createCallbackFcn(app, @FeedbackMenuSelected, true);
            app.FeedbackMenu.Text = 'Feedback';
            app.AboutusMenu = uimenu(app.UIFigure);
            app.AboutusMenu.Text = 'About us';
            app.WhoweareMenu = uimenu(app.AboutusMenu);
            app.WhoweareMenu.MenuSelectedFcn = createCallbackFcn(app, @WhoweareMenuSelected, true);
            app.WhoweareMenu.Text = 'Who we are?';
            app.WhatdowedoMenu = uimenu(app.AboutusMenu);
            app.WhatdowedoMenu.Text = 'What do we do?';
            app.TeamDontBandwidthUsMenu = uimenu(app.AboutusMenu);
            app.TeamDontBandwidthUsMenu.Text = 'Team Dont Bandwidth Us';
            app.VivekAsharMenu = uimenu(app.TeamDontBandwidthUsMenu);
            app.VivekAsharMenu.MenuSelectedFcn = createCallbackFcn(app, @VivekAsharMenuSelected, true);
            app.VivekAsharMenu.Text = 'Vivek Ashar';
            app.PreetPanditMenu = uimenu(app.TeamDontBandwidthUsMenu);
            app.PreetPanditMenu.MenuSelectedFcn = createCallbackFcn(app, @PreetPanditMenuSelected, true);
            app.PreetPanditMenu.Text = 'Preet Pandit';
            app.PriyadarshiniMenu_2 = uimenu(app.TeamDontBandwidthUsMenu);
            app.PriyadarshiniMenu_2.MenuSelectedFcn = createCallbackFcn(app, @PriyadarshiniMenu_2Selected, true);
            app.PriyadarshiniMenu_2.Text = 'Priyadarshini';
            app.EnterthefullpathtothedaywisecalenderworkbookEditFieldLabel = uilabel(app.UIFigure);
            app.EnterthefullpathtothedaywisecalenderworkbookEditFieldLabel.Text = 'Enter the full path to the day-wise calender workbook';
            app.EnterthefullpathtothedaywisecalenderworkbookEditField = uieditfield(app.UIFigure, 'text');
            app.EnterthefullpathtothedaywisecalenderworkbookEditField.Value = 'C:\Users\vv972\OneDrive\Documents\MATLAB\Excel case study\Excel Automation\Automation_Sample Calender_v0.1.xlsx';
            app.GeneratethemastercalenderButton = uibutton(app.UIFigure, 'push');
            app.GeneratethemastercalenderButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratethemastercalenderButtonPushed, true);
            app.GeneratethemastercalenderButton.Text = 'Generate the master calender';
            app.GeneratethefacultycalenderButton = uibutton(app.UIFigure, 'push');
            app.GeneratethefacultycalenderButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratethefacultycalenderButtonPushed, true);
            app.GeneratethefacultycalenderButton.Text = 'Generate the faculty calender';
            app.GeneratethefacultyloadsheetButton = uibutton(app.UIFigure, 'push');
            app.GeneratethefacultyloadsheetButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratethefacultyloadsheetButtonPushed, true);
            app.GeneratethefacultyloadsheetButton.Text = 'Generate the faculty load sheet';
            app.EnterthesheetnameofthedaywisecalendersheetEditFieldLabel = uilabel(app.UIFigure);
            app.EnterthesheetnameofthedaywisecalendersheetEditFieldLabel.Text = 'Enter the sheet name of the day-wise calender sheet';
            app.EnterthesheetnameofthedaywisecalendersheetEditField = uieditfield(app.UIFigure, 'text');
            app.MonthLabel = uilabel(app.UIFigure);
            app.MonthLabel.Text = 'Month';
            app.MonthDropDown = uidropdown(app.UIFigure);
            app.MonthDropDown.Items = {'January', 'February', 'March', 'April', 'June', 'July', 'August', 'September', 'October', 'November', 'December'};
            app.MonthDropDown.Value = 'January';
            app.InitiativeDropDownLabel = uilabel(app.UIFigure);
            app.InitiativeDropDownLabel.Text = 'Initiative';
            app.InitiativeDropDown = uidropdown(app.UIFigure);
            app.InitiativeDropDown.Items = {'GENESIS', 'GENESIS PRO', 'BUILD / STEP UP', 'OPEN TRAININGS', 'STEPin', 'OTHERS'};
            app.InitiativeDropDown.Value = 'GENESIS';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'LTTS-Logo-New.jpg';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end